classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinearRegressionUIFigure  matlab.ui.Figure
        ModelDropDown             matlab.ui.control.DropDown
        ModelDropDownLabel        matlab.ui.control.Label
        PlotsPanel                matlab.ui.container.Panel
        PowerAxes                 matlab.ui.control.UIAxes
        ExponentialAxes           matlab.ui.control.UIAxes
        GrowthRateAxes            matlab.ui.control.UIAxes
        LinearAxes                matlab.ui.control.UIAxes
        LoadButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Tb % Data table
        x % x coordinates
        y % y coordinates
        f % curve fit
        isDataLoaded % Description
        firstSelection % Description
    end
    
    methods (Access = private)
        
        function [Tb, isValid] = readExcelFile(~, file)
            Tb = readtable(file);
            vars = Tb.Properties.VariableNames;
            if ismember('x', vars) && ismember('y', vars)
                isValid = 1;
            else
                msgbox(sprintf('Choose a valid file%cA valid file contains a column for x data with title "x" and a column for y data with title "y"',...
                    newline),'Error', 'help');
                isValid = 0;
            end
        end
        
        function [x, y] = readData(app)
            vars = app.Tb.Properties.VariableNames;
            xCols = find(contains(vars,'x'));
            yCols = find(contains(vars,'y'));
            x = app.Tb{:,xCols};
            y = app.Tb{:,yCols};
        end
        
        function [Y, r] = linear(app)
            [Y, r] = linearModel(app.y, app.x);
            app.LinearAxes.Title.String = "Linear model";
            app.LinearAxes.XLabel.String = "X";
            app.LinearAxes.YLabel.String = "Y";
            scatter(app.LinearAxes, app.x, app.y);
            hold(app.LinearAxes, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'pchip');
            plot(app.LinearAxes, xx, yy);
            T = text(app.LinearAxes, app.LinearAxes.XTick(int32(end / 2)), app.LinearAxes.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
            T.FontSize = 18;
        end
        
        function [Y, r] = exponent(app)
            [Y, r] = expModel(app.y, app.x);
            app.ExponentialAxes.Title.String = "Exponential model";
            app.ExponentialAxes.XLabel.String = "X";
            app.ExponentialAxes.YLabel.String = "Y";
            scatter(app.ExponentialAxes, app.x, app.y);
            hold(app.ExponentialAxes, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'pchip');
            plot(app.ExponentialAxes, xx, yy);
            T = text(app.ExponentialAxes, app.ExponentialAxes.XTick(int32(end / 2)), app.ExponentialAxes.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
            T.FontSize = 18;
        end
        
        function [Y, r] = growthRate(app)
            [Y, r] = growthRateModel(app.y, app.x);
            app.GrowthRateAxes.Title.String = "Growth Rate model";
            app.GrowthRateAxes.XLabel.String = "X";
            app.GrowthRateAxes.YLabel.String = "Y";
            scatter(app.GrowthRateAxes, app.x, app.y);
            hold(app.GrowthRateAxes, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'pchip');
            plot(app.GrowthRateAxes, xx, yy);
            T = text(app.GrowthRateAxes, app.GrowthRateAxes.XTick(int32(end / 2)), app.GrowthRateAxes.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
            T.FontSize = 18;
        end
        
        function [Y, r] = power(app)
            [Y, r] = powerModel(app.y, app.x);
            app.PowerAxes.Title.String = "Power model";
            app.PowerAxes.XLabel.String = "X";
            app.PowerAxes.YLabel.String = "Y";
            scatter(app.PowerAxes, app.x, app.y);
            hold(app.PowerAxes, 'on');
            xx  = linspace(app.x(1), app.x(end), 100);
            yy = interp1(app.x, Y, xx, 'pchip');
            plot(app.PowerAxes, xx, yy);
            T = text(app.PowerAxes, app.PowerAxes.XTick(int32(end / 2)), app.PowerAxes.YTick(int32(end / 2)), ['r =  ' num2str(r)]);
            T.FontSize = 18;
        end
        
        function r = plots(app)
            [~, rLin] = linear(app);
            [~, rExp] = exponent(app);
            [~, rPow] = power(app);
            [~, rGr] = growthRate(app);
            
            r = [rLin, rExp, rPow, rGr];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotsPanel.Visible = 0;
            app.ModelDropDown.Items = " ";
            app.isDataLoaded = 0;
        end

        % Size changed function: LinearRegressionUIFigure
        function LinearRegressionUIFigureSizeChanged(app, event)
            position = app.LinearRegressionUIFigure.Position;
            bP = app.LoadButton.Position;
            gP = app.PlotsPanel.Position;
            bP(1) = position(3) - 120;
            gP(3) = position(3) - 20;
            gP(4) = position(4) - 100;
            app.LoadButton.Position = bP;
            app.PlotsPanel.Position = gP;
            %disp(bP);
        end

        % Size changed function: PlotsPanel
        function PlotsPanelSizeChanged(app, event)
            position = app.PlotsPanel.Position;
            h = (position(4) - 20) / 4;
            UI1P = app.LinearAxes.Position;
            UI2P = app.ExponentialAxes.Position;
            UI3P = app.GrowthRateAxes.Position;
            UI4P = app.PowerAxes.Position;
            
            UI1P(1) = 1;
            UI2P(1) = 1;
            UI3P(1) = 1;
            UI4P(1) = 1;
            UI1P(2) = 3 .* h;
            UI2P(2) = 2 .* h;
            UI3P(2) = h;
            UI4P(2) = 1;
            UI1P(3) = position(3);
            UI2P(3) = position(3);
            UI3P(3) = position(3);
            UI4P(3) = position(3);
            UI1P(4) = h;
            UI2P(4) = h;
            UI3P(4) = h;
            UI4P(4) = h;
            app.LinearAxes.Position = UI1P;
            app.ExponentialAxes.Position = UI2P;
            app.GrowthRateAxes.Position = UI3P;
            app.PowerAxes.Position = UI4P;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cla(app.LinearAxes);
            cla(app.ExponentialAxes);
            cla(app.GrowthRateAxes);
            cla(app.PowerAxes);
            app.firstSelection = 0;
            app.ModelDropDown.Items = ["Linear model", "Exponential model", "Growth Rate model", "Power model", " "];
            app.ModelDropDown.Value = " ";
            other = 1;
            while other
                [file,path] = uigetfile({'*.*';'*.csv';'*.xlsx';'*.mat'},...
                    'File Selector');
                data = fullfile(path, file);
                xlsx = contains(data,'.xlsx',IgnoreCase=true);
                csv = contains(data,'.csv',IgnoreCase=true);
                mat = contains(data,'.mat',IgnoreCase=true);
                type = [xlsx csv mat];
                switch find(type==1)
                    case 1 
                        [app.Tb, isValid] = readExcelFile(app, data);
                        if isValid
                            [app.x, app.y] = readData(app);
                            other = 0;
                        end
                    case 2
                        [app.Tb, isValid] = readExcelFile(app, data);
                        if isValid
                            [app.x, app.y] = readData(app);
                            other = 0;
                        end
                    case 3
                        d = load(data,"x","y");
                        app.x = d.x;
                        app.y = d.y;
                        other = 0;
                    otherwise
                        other = 1;
                end
            end
            app.isDataLoaded = 1;
            app.PlotsPanel.Visible = 1;
            r = plots(app);
            model = bestSolution(r(1), r(2), r(3), r(4));
            switch model
                case 0
                    T = text(app.LinearAxes, app.LinearAxes.XTick(int32(end / 2)), app.LinearAxes.YTick(int32(end / 2)), ['r =  ' num2str(r(1))]);
                    T.FontSize = 18;
                    set(T, 'Color',[0, 1 ,0])
                    app.LinearAxes.Title.String = app.LinearAxes.Title.String + " [Best Fit]";
                    app.ExponentialAxes.Title.String = erase(app.ExponentialAxes.Title.String," [Best Fit]");
                    app.GrowthRateAxes.Title.String = erase(app.GrowthRateAxes.Title.String," [Best Fit]");
                    app.PowerAxes.Title.String = erase(app.PowerAxes.Title.String," [Best Fit]");
                case 1
                    T = text(app.ExponentialAxes, app.ExponentialAxes.XTick(int32(end / 2)), app.ExponentialAxes.YTick(int32(end / 2)), ['r =  ' num2str(r(2))]);
                    T.FontSize = 18;
                    set(T, 'Color',[0, 1 ,0])
                    app.ExponentialAxes.Title.String = app.ExponentialAxes.Title.String + " [Best Fit]";
                    app.LinearAxes.Title.String = erase(app.LinearAxes.Title.String," [Best Fit]");
                    app.GrowthRateAxes.Title.String = erase(app.GrowthRateAxes.Title.String," [Best Fit]");
                    app.PowerAxes.Title.String = erase(app.PowerAxes.Title.String," [Best Fit]");
                case 2
                    T = text(app.GrowthRateAxes, app.GrowthRateAxes.XTick(int32(end / 2)), app.GrowthRateAxes.YTick(int32(end / 2)), ['r =  ' num2str(r(3))]);
                    T.FontSize = 18;
                    set(T, 'Color',[0, 1 ,0])
                    app.GrowthRateAxes.Title.String = app.GrowthRateAxes.Title.String + " [Best Fit]";
                    app.LinearAxes.Title.String = erase(app.LinearAxes.Title.String," [Best Fit]");
                    app.ExponentialAxes.Title.String = erase(app.ExponentialAxes.Title.String," [Best Fit]");
                    app.PowerAxes.Title.String = erase(app.PowerAxes.Title.String," [Best Fit]");
                case 3
                    T = text(app.PowerAxes, app.PowerAxes.XTick(int32(end / 2)), app.PowerAxes.YTick(int32(end / 2)), ['r =  ' num2str(r(4))]);
                    T.FontSize = 18;
                    set(T, 'Color',[0, 1 ,0])
                    app.PowerAxes.Title.String = app.PowerAxes.Title.String + " [Best Fit]";
                    app.LinearAxes.Title.String = erase(app.LinearAxes.Title.String," [Best Fit]");
                    app.ExponentialAxes.Title.String = erase(app.ExponentialAxes.Title.String," [Best Fit]");
                    app.GrowthRateAxes.Title.String = erase(app.GrowthRateAxes.Title.String," [Best Fit]");
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            
            switch value
                case "Linear model"
                    app.LinearAxes.Title.String = app.LinearAxes.Title.String + " [Your Choice]";
                    app.ExponentialAxes.Title.String = erase(app.ExponentialAxes.Title.String," [Your Choice]");
                    app.GrowthRateAxes.Title.String = erase(app.GrowthRateAxes.Title.String," [Your Choice]");
                    app.PowerAxes.Title.String = erase(app.PowerAxes.Title.String," [Your Choice]");
                case "Exponential model"
                    app.ExponentialAxes.Title.String = app.ExponentialAxes.Title.String + " [Your Choice]";
                    app.LinearAxes.Title.String = erase(app.LinearAxes.Title.String," [Your Choice]");
                    app.GrowthRateAxes.Title.String = erase(app.GrowthRateAxes.Title.String," [Your Choice]");
                    app.PowerAxes.Title.String = erase(app.PowerAxes.Title.String," [Your Choice]");
                case "Power model"
                    app.PowerAxes.Title.String = app.PowerAxes.Title.String + " [Your Choice]";
                    app.LinearAxes.Title.String = erase(app.LinearAxes.Title.String," [Your Choice]");
                    app.GrowthRateAxes.Title.String = erase(app.GrowthRateAxes.Title.String," [Your Choice]");
                    app.ExponentialAxes.Title.String = erase(app.ExponentialAxes.Title.String," [Your Choice]");
                case "Growth Rate model"
                    app.GrowthRateAxes.Title.String = app.GrowthRateAxes.Title.String + " [Your Choice]";
                    app.LinearAxes.Title.String = erase(app.LinearAxes.Title.String," [Your Choice]");
                    app.ExponentialAxes.Title.String = erase(app.ExponentialAxes.Title.String," [Your Choice]");
                    app.PowerAxes.Title.String = erase(app.PowerAxes.Title.String," [Your Choice]");
            end
        end

        % Drop down opening function: ModelDropDown
        function ModelDropDownOpening(app, event)
            if app.isDataLoaded
                if app.firstSelection ~= 0
                    app.ModelDropDown.Items = ["Linear model", "Exponential model", "Growth Rate model", "Power model"];
                else
                    app.firstSelection = 1;
                end
            else
                msgbox('Load data first','Error', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinearRegressionUIFigure = uifigure('Visible', 'off');
            app.LinearRegressionUIFigure.Name = 'Linear Regression';
            app.LinearRegressionUIFigure.SizeChangedFcn = createCallbackFcn(app, @LinearRegressionUIFigureSizeChanged, true);
            app.LoadButton = uibutton(app.LinearRegressionUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotsPanel = uipanel(app.LinearRegressionUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.PlotsPanel.SizeChangedFcn = createCallbackFcn(app, @PlotsPanelSizeChanged, true);
            app.LinearAxes = uiaxes(app.PlotsPanel);
            title(app.LinearAxes, 'Title')
            xlabel(app.LinearAxes, 'X')
            ylabel(app.LinearAxes, 'Y')
            zlabel(app.LinearAxes, 'Z')
            app.GrowthRateAxes = uiaxes(app.PlotsPanel);
            title(app.GrowthRateAxes, 'Title')
            xlabel(app.GrowthRateAxes, 'X')
            ylabel(app.GrowthRateAxes, 'Y')
            zlabel(app.GrowthRateAxes, 'Z')
            app.ExponentialAxes = uiaxes(app.PlotsPanel);
            title(app.ExponentialAxes, 'Title')
            xlabel(app.ExponentialAxes, 'X')
            ylabel(app.ExponentialAxes, 'Y')
            zlabel(app.ExponentialAxes, 'Z')
            app.PowerAxes = uiaxes(app.PlotsPanel);
            title(app.PowerAxes, 'Title')
            xlabel(app.PowerAxes, 'X')
            ylabel(app.PowerAxes, 'Y')
            zlabel(app.PowerAxes, 'Z')
            app.ModelDropDownLabel = uilabel(app.LinearRegressionUIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LinearRegressionUIFigure);
            app.ModelDropDown.Items = {};
            app.ModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ModelDropDownOpening, true);
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = {};
            % Show the figure after all components are created
            app.LinearRegressionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinearRegressionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinearRegressionUIFigure)
        end
    end
end